classdef Auto_Attend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        UITable                       matlab.ui.control.Table
        LoaddatabaseButton            matlab.ui.control.Button
        CreateAttendanceRecordButton  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        GetRecordButton               matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddatabaseButton
        function LoaddatabaseButtonPushed(app, event)
            run load_database.m
            app.EditField.Value='Database loaded';
        end

        % Button pushed function: CreateAttendanceRecordButton
        function CreateAttendanceRecordButtonPushed(app, event)
            run main.m
            app.EditField_2.Value="Record created";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            run Save_record.m
            app.EditField_3.Value='Record saved';
        end

        % Button pushed function: GetRecordButton
        function GetRecordButtonPushed(app, event)
            [file,path]=uigetfile('*.xlsx');
            record = readtable(fullfile(path, file));
            app.UITable.Data=record;
            app.UITable.ColumnName = record.Properties.VariableNames;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'HomePage_Image.jpg';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Monday'; 'Tuesday'; 'Wednesday'; 'Thursday'; 'Friday'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.LoaddatabaseButton = uibutton(app.UIFigure, 'push');
            app.LoaddatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatabaseButtonPushed, true);
            app.LoaddatabaseButton.Text = 'Load database';
            app.CreateAttendanceRecordButton = uibutton(app.UIFigure, 'push');
            app.CreateAttendanceRecordButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAttendanceRecordButtonPushed, true);
            app.CreateAttendanceRecordButton.Text = 'Create Attendance Record';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.GetRecordButton = uibutton(app.UIFigure, 'push');
            app.GetRecordButton.ButtonPushedFcn = createCallbackFcn(app, @GetRecordButtonPushed, true);
            app.GetRecordButton.Text = 'Get Record';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'WordArt_image.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Auto_Attend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end